classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        mSair              matlab.ui.container.Menu
        AtualizarButton    matlab.ui.control.Button
        UITable            matlab.ui.control.Table
        ButtonGroup        matlab.ui.container.ButtonGroup
        TODOSButton        matlab.ui.control.RadioButton
        PontoMdioButton    matlab.ui.control.RadioButton
        RK4Button          matlab.ui.control.RadioButton
        EulerMButton       matlab.ui.control.RadioButton
        ODE45Button        matlab.ui.control.RadioButton
        RK2Button          matlab.ui.control.RadioButton
        EulerButton        matlab.ui.control.RadioButton
        DadosPanel         matlab.ui.container.Panel
        EY0                matlab.ui.control.NumericEditField
        y0EditFieldLabel   matlab.ui.control.Label
        EN                 matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        EB                 matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        EA                 matlab.ui.control.NumericEditField
        aEditFieldLabel    matlab.ui.control.Label
        ftyEditField       matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
             syms  y(t)
            strF = app.ftyEditField.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.EA.Value;
            b = app.EB.Value;
            n = app.EN.Value;
            y0 = app.EY0.Value;

            metodoSelecionado = app.ButtonGroup.SelectedObject.Text;

            erroFuncao = MException("MATLAB:AtualizarButton:badF","Introduza uma função em t e y");
            
         try 
             try
                 z = f(t,y);
                 app.ftyEditField.BackgroundColor = 'w';
             catch
                 app.ftyEditField.BackgroundColor = [1,0,0];
                 throw(erroFuncao)
             end

             yEuler = Neuler(f,a,b,n,y0);
             yPM = PontoMedio(f,a,b,n,y0);
             yEulerM = MEuler(f,a,b,n,y0);
             yRK2 = NRK2(f,a,b,n,y0);
             yRK4 = NRK4(f,a,b,n,y0);
              sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
              g = @(t) eval(vectorize(char(sExata)));
              h = (b-a)/n;
              t = a:h:b;
              yExata = g(t);
              erroEuler = abs(yExata-yEuler);
              erroEulerM = abs(yExata-yEulerM);
              erroRK2 = abs(yExata-yRK2);
              erroRK4 = abs(yExata-yRK4);
              erroPM = abs(yExata-yPM);

              plot(app.UIAxes,t,yExata,"-r*")
              hold(app.UIAxes,"on")

           switch metodoSelecionado
               case "Euler"
                    plot(app.UIAxes,t,yEuler,"-go")
                    legenda = {'Exata','Euler'};
                    tabela = [t.',yExata.',yEuler.',erroEuler.'];
                    nomeColunas = {'t','Exata','Euler','erroEuler'};

               case "EulerM"
                   plot(app.UIAxes,t,yEulerM,"-go")
                    legenda = {'Exata','EulerM'};
                    tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                    nomeColunas = {'t','Exata','EulerM','erroEulerM'};
               case "RK2"
                   plot(app.UIAxes,t,yRK2,"-go")
                    legenda = {'Exata','RK2'};
                    tabela = [t.',yExata.',yRK2.',erroRK2.'];
                    nomeColunas = {'t','Exata','RK2','erroRK2'};
               case "RK4"
                    plot(app.UIAxes,t,yRK4,"-go")
                    legenda = {'Exata','RK4'};
                    tabela = [t.',yExata.',yRK4.',erroRK4.'];
                    nomeColunas = {'t','Exata','RK4','erroRK4'};
               case "ODE45"
                  

               case "Ponto-Médio"
                   plot(app.UIAxes,t,yPM,"-go")
                    legenda = {'Exata','PM'};
                    tabela = [t.',yExata.',yPM.',erroPM.'];
                    nomeColunas = {'t','Exata','PM','erroPM'};
                   
               case "TODOS"

                   plot(app.UIAxes,t,yEuler,"-go");
                   plot(app.UIAxes,t,yEulerM,"-y*");
                   plot(app.UIAxes,t,yRK2,"-bo");
                   plot(app.UIAxes,t,yRK4,"-kd");
                   plot(app.UIAxes,t,yPM,"-wo");
                   
                   
                    legenda = {'Exata','Euler','EulerM','RK2','RK4','PontoMédio'};
                    tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yPM.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroPM.'];
                    nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','PontoMédio','erroEuler','erroEulerM','erroRK2','erroRK4','erroPM'};
           end
            
              T = array2table(tabela);
              app.UITable.Data = T;
              app.UITable.ColumnName = nomeColunas;
             
              hold(app.UIAxes,"off")
              grid(app.UIAxes,"on")
              legend(app.UIAxes,legenda);

           catch ME  
               errordlg(ME.message,"Erro","modal")
         end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.mSair = uimenu(app.FileMenu);
            app.mSair.Text = 'Sair';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DadosPanel = uipanel(app.UIFigure);
            app.DadosPanel.Title = 'Dados';
            app.ftyEditFieldLabel = uilabel(app.DadosPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) =';
            app.ftyEditField = uieditfield(app.DadosPanel, 'text');
            app.ftyEditField.Value = '-2*y*t';
            app.aEditFieldLabel = uilabel(app.DadosPanel);
            app.aEditFieldLabel.Text = 'a';
            app.EA = uieditfield(app.DadosPanel, 'numeric');
            app.EA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosPanel);
            app.bEditFieldLabel.Text = 'b';
            app.EB = uieditfield(app.DadosPanel, 'numeric');
            app.EB.Value = 2;
            app.nEditFieldLabel = uilabel(app.DadosPanel);
            app.nEditFieldLabel.Text = 'n';
            app.EN = uieditfield(app.DadosPanel, 'numeric');
            app.EN.Value = 10;
            app.y0EditFieldLabel = uilabel(app.DadosPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.EY0 = uieditfield(app.DadosPanel, 'numeric');
            app.EY0.Value = 2;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.EulerButton = uiradiobutton(app.ButtonGroup);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.RK2Button = uiradiobutton(app.ButtonGroup);
            app.RK2Button.Text = 'RK2';
            app.ODE45Button = uiradiobutton(app.ButtonGroup);
            app.ODE45Button.Text = 'ODE45';
            app.EulerMButton = uiradiobutton(app.ButtonGroup);
            app.EulerMButton.Text = 'EulerM';
            app.RK4Button = uiradiobutton(app.ButtonGroup);
            app.RK4Button.Text = 'RK4';
            app.PontoMdioButton = uiradiobutton(app.ButtonGroup);
            app.PontoMdioButton.Text = 'Ponto-Médio';
            app.TODOSButton = uiradiobutton(app.ButtonGroup);
            app.TODOSButton.Text = 'TODOS';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AtualizarButton = uibutton(app.UIFigure, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end